classdef Final_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        IntroductionTab                 matlab.ui.container.Tab
        HeatDistributioninPCBLabel      matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        NEXTButton                      matlab.ui.control.Button
        ParameterTab                    matlab.ui.container.Tab
        ParameterManualListBoxLabel     matlab.ui.control.Label
        ParameterManualListBox          matlab.ui.control.ListBox
        NEXTButton_2                    matlab.ui.control.Button
        InputTab                        matlab.ui.container.Tab
        AnalysisTypeDropDownLabel       matlab.ui.control.Label
        AnalysisTypeDropDown            matlab.ui.control.DropDown
        HeatDistributionConstantEditFieldLabel  matlab.ui.control.Label
        HeatDistributionConstantEditField  matlab.ui.control.NumericEditField
        TemperatureofLeftwallEditFieldLabel  matlab.ui.control.Label
        TemperatureofLeftwallEditField  matlab.ui.control.NumericEditField
        TemperatureifRightWallEditFieldLabel  matlab.ui.control.Label
        TemperatureifRightWallEditField  matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        TemperatureofTopWallEditFieldLabel  matlab.ui.control.Label
        TemperatureofTopWallEditField   matlab.ui.control.NumericEditField
        TemperatureofbottomWallEditFieldLabel  matlab.ui.control.Label
        TemperatureofbottomWallEditField  matlab.ui.control.NumericEditField
        PCBlengthEditFieldLabel         matlab.ui.control.Label
        PCBlengthEditField              matlab.ui.control.NumericEditField
        PCBwidthEditFieldLabel          matlab.ui.control.Label
        PCBwidthEditField               matlab.ui.control.NumericEditField
        LengthMustBePositiveLabel       matlab.ui.control.Label
        InitialTemperatureEditFieldLabel  matlab.ui.control.Label
        InitialTemperatureEditField     matlab.ui.control.NumericEditField
        TimeStepsEditFieldLabel         matlab.ui.control.Label
        TimeStepsEditField              matlab.ui.control.NumericEditField
        x_coordEditFieldLabel           matlab.ui.control.Label
        x_coordEditField                matlab.ui.control.NumericEditField
        y_coordEditFieldLabel           matlab.ui.control.Label
        y_coordEditField                matlab.ui.control.NumericEditField
        StartSimulationButton           matlab.ui.control.Button
        InvalidInputLabel               matlab.ui.control.Label
        HotspotDropDownLabel            matlab.ui.control.Label
        HotspotDropDown                 matlab.ui.control.DropDown
        x_coordEditFieldLabel_2         matlab.ui.control.Label
        SimulationTab                   matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        ValEditFieldLabel               matlab.ui.control.Label
        ValEditField                    matlab.ui.control.NumericEditField
        x_coordEditFieldLabel_3         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ShowButtonPushed(app, event)
%             app.EditField.Value='Iftekhar Sadat Siyam';
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            app.InvalidInputLabel.Visible="off";    

               app.LengthMustBePositiveLabel.Visible="off";

%                app.LengthTooSmallLabel.Visible="off";
    % Define tabs in correct order manually
    tabs = [app.IntroductionTab, app.ParameterTab, app.InputTab, app.SimulationTab];
    
    % Find current tab index
    currentTab = app.TabGroup.SelectedTab;
    currentIndex = find(tabs == currentTab);
    
    % Go to next tab (stop at last)
    if currentIndex < length(tabs)
        app.TabGroup.SelectedTab = tabs(currentIndex + 1);
    end


               %length
               Lx = app.PCBlengthEditField.Value;

               %width
               Ly = app.PCBwidthEditField.Value;

               if Lx<=0
                    app.LengthMustBePositiveLabel.Visible="on";
                    return;
               end

               if Lx<.1
                   app.LengthTooSmallLabel.Visible="on";
                   return;
               end


               if Ly<=0
                     app.LengthMustBePositiveLabel.Visible="on";
                     return;
               end

               if Ly<.1
                   app.LengthTooSmallLabel.Visible="on";
                   return;
               end


               xq=round(app.x_coordEditField.Value*50);
               yq=round(app.y_coordEditField.Value*50);

               if xq>Lx*50 || xq<0
                   app.InvalidInputLabel.Visible="on";  
                   return;
               end


               if yq>Ly*50 || yq<0
                   app.InvalidInputLabel.Visible="on";  
                   return;
               end


               Nx = round(50*Lx);
               Ny = round(50*Ly);

               dx = Lx / (Nx - 1);
               dy = Ly / (Ny - 1);

               dt = 0.01;


               %diffusivity constant
               alpha = app.HeatDistributionConstantEditField.Value; 

               if alpha>=1
                    app.InvalidInputLabel.Visible="on";
                    return;
               end

               if alpha<=0
                   app.InvalidInputLabel.Visible="on";
                    return;
               end


               time_steps = app.TimeStepsEditField.Value; 

               if time_steps<=50
                    app.InvalidInputLabel.Visible="on";
                    return;
               end


               x = linspace(0, Lx, Nx);
               y = linspace(0, Ly, Ny);
               [X, Y] = meshgrid(x, y);

               T = zeros(Ny, Nx);


               %initial temperature of the metal
               init_temp=app.InitialTemperatureEditField.Value;
               T(:,:) = init_temp; 
              t=readtable("hotspot.xlsx","Sheet",1);
              app.UITable.Data=t
%               t.Properties.VariableNames(1)='x Data'
%               t.Properties.VariableNames(2)='y Data'
%               t.Properties.VariableNames(1)='temp Data'
              app.UITable.ColumnName=t.Properties.VariableNames;
              xh=table2array(t(:,'x'));
              xh=table2array(t(:,'y'));
              temph=table2array(t(:,'temp'));
              radh=table2array(t(:,'radius'))
                
                % --- Hotspot code here ---
                if strcmpi(app.HotspotDropDown.Value, 'Enable')
                hotspot_data = app.UITable.Data;
                for k = 1:size(hotspot_data,1)
                    x_hs = round(table2array(hotspot_data(k,1))*50);
                    y_hs = round(table2array(hotspot_data(k,2))*50);
                    temp_hs = table2array(hotspot_data(k,3));
                     rad_hs=table2array(hotspot_data(k,4))
                            if x_hs > 0 && x_hs <= Nx && y_hs > 0 && y_hs <= Ny
                                            radius = round(rad_hs*50);  % adjustable
                                x_range = max(1, x_hs - radius) : min(Nx, x_hs + radius);
                                y_range = max(1, y_hs - radius) : min(Ny, y_hs + radius);
                                T(y_range, x_range) = temp_hs;
                            end
                end
                end


               k=alpha*dt;

               kx=k/dx^2;
               ky=k/dy^2;


               % Boundary Temperatures
               bottom_wall = app.TemperatureofbottomWallEditField.Value;
               top_wall = app.TemperatureofTopWallEditField.Value;
               left_wall = app.TemperatureofLeftwallEditField.Value;
               right_wall = app.TemperatureifRightWallEditField.Value;


               % Implementing Boundary Conditions
               T(1,:) = bottom_wall;
               T(Ny,:) = top_wall;
               T(:,1) = left_wall;
               T(:,Nx) = right_wall;


               % Temperature at the corner points
               T(1,1) = (bottom_wall + left_wall) / 2;
               T(1,Nx) = (bottom_wall + right_wall) / 2;
               T(Ny,1) = (top_wall + left_wall) / 2;
               T(Ny,Nx) = (top_wall + right_wall) / 2;


               % Temperature of the metal
               for t = 1:time_steps
                    T_old = T;

                    for i = 2:Nx-1
                         for j = 2:Ny-1
                                T(j,i) = T_old(j,i) + kx * (T_old(j,i+1) - 2 * T_old(j,i) + T_old(j,i-1))...
                                      + ky * (T_old(j+1,i) - 2 * T_old(j,i) + T_old(j-1,i));
                         end
                    end
                    if strcmpi(app.HotspotDropDown.Value, 'Enable')
                    if strcmpi(app.AnalysisTypeDropDown.Value, 'Steady State')
                                    hotspot_data = app.UITable.Data;
                            for k = 1:size(hotspot_data,1)
                                x_hs = round(table2array(hotspot_data(k,1))*50);
                                y_hs = round(table2array(hotspot_data(k,2))*50);
                                temp_hs = table2array(hotspot_data(k,3));
                                 rad_hs=table2array(hotspot_data(k,4))
                                        if x_hs > 0 && x_hs <= Nx && y_hs > 0 && y_hs <= Ny
                                                        radius = round(rad_hs*50);  % adjustable
                                            x_range = max(1, x_hs - radius) : min(Nx, x_hs + radius);
                                            y_range = max(1, y_hs - radius) : min(Ny, y_hs + radius);
                                            T(y_range, x_range) = temp_hs;
                                        end
                            end
                    end
                    end

                    %Simulating the plot
                    if mod(t, 500) == 0
                         contourf(app.UIAxes, X, Y, T, 40, 'linecolor', 'non'); 
                         colormap(app.UIAxes,"jet");
                         colorbar(app.UIAxes);
                         title(app.UIAxes, sprintf('Temperature at time step %d', t));
                         xlabel(app.UIAxes, 'Length');
                         ylabel(app.UIAxes, 'Width');
                         drawnow;
                     end
               end
               
               if xq<.02 && yq<.02
                   app.ValEditField.Value=(bottom_wall+left_wall)/2;

               elseif xq<.02
                   app.ValEditField.Value=left_wall;


               elseif yq<.02
                   app.ValEditField.Value=bottom_wall;


               else
                   app.ValEditField.Value=T(yq,xq);
               end
        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
        
    % Define tabs in correct order manually
    tabs = [app.IntroductionTab, app.ParameterTab, app.InputTab, app.SimulationTab];
    
    % Find current tab index
    currentTab = app.TabGroup.SelectedTab;
    currentIndex = find(tabs == currentTab);
    
    % Go to next tab (stop at last)
    if currentIndex < length(tabs)
        app.TabGroup.SelectedTab = tabs(currentIndex + 1);
    end

        end

        % Button pushed function: NEXTButton_2
        function NEXTButton_2Pushed(app, event)
               % Define tabs in correct order manually
    tabs = [app.IntroductionTab, app.ParameterTab, app.InputTab, app.SimulationTab];
    
    % Find current tab index
    currentTab = app.TabGroup.SelectedTab;
    currentIndex = find(tabs == currentTab);
    
    % Go to next tab (stop at last)
    if currentIndex < length(tabs)
        app.TabGroup.SelectedTab = tabs(currentIndex + 1);
    end
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IntroductionTab = uitab(app.TabGroup);
            app.IntroductionTab.Title = 'Introduction';
            app.HeatDistributioninPCBLabel = uilabel(app.IntroductionTab);
            app.HeatDistributioninPCBLabel.Text = 'Heat Distribution in PCB';
            app.TextArea = uitextarea(app.IntroductionTab);
            app.TextArea.Value = {'This App simulates the heat distribution in a printed circuit board (PCB) shown is an example of a steady state and impulse temperature distribution on a PCB. Warmer Areas are represented by colors from red to yellow ,while cooler areas are depicted in green and blue.'};
            app.Image = uiimage(app.IntroductionTab);
            app.Image.ImageSource = 'Media.jpg';
            app.NEXTButton = uibutton(app.IntroductionTab, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Text = 'NEXT';
            app.ParameterTab = uitab(app.TabGroup);
            app.ParameterTab.Title = 'Parameter';
            app.ParameterManualListBoxLabel = uilabel(app.ParameterTab);
            app.ParameterManualListBoxLabel.Text = 'Parameter Manual';
            app.ParameterManualListBox = uilistbox(app.ParameterTab);
            app.ParameterManualListBox.Items = {'| Label                                            | Description                                                                  ', '', 'Heat Distribution Constant              Thermal diffusivity constant of the material (e.g., 0.00011).          ', 'Temperature of Left Wall                 Temperature in °C (e.g., 30).                                                ', 'Temperature of Right Wall              Temperature in °C (e.g., 30).                                                ', 'Temperature of Top Wall                 Temperature in °C (e.g., 50).                                           ', 'Temperature of Bottom Wall           Temperature in °C (e.g., 50).                                                ', 'PCB Length                                     Length of PCB in meters. Must be a positive number                  ', 'PCB Width                                      Width of PCB in meters. Must be a positive number                    ', 'Initial Temperature                          Initial internal temperature of the PCB.                                      ', 'Final Time                                       Duration (in seconds or time steps) of the simulation.                       ', 'x_coord / y_coord                           Coordinates to extract or observe specific temperature points inside the PCB.', 'Hotspot Table                                  Co-ordinates of hotspot ,It''s radius and Temperature ( Read from Excel File)'};
            app.ParameterManualListBox.Value = '| Label                                            | Description                                                                  ';
            app.NEXTButton_2 = uibutton(app.ParameterTab, 'push');
            app.NEXTButton_2.ButtonPushedFcn = createCallbackFcn(app, @NEXTButton_2Pushed, true);
            app.NEXTButton_2.Text = 'NEXT';
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.AnalysisTypeDropDownLabel = uilabel(app.InputTab);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.InputTab);
            app.AnalysisTypeDropDown.Items = {'Impulse', 'Steady State', '', ''};
            app.AnalysisTypeDropDown.Value = 'Impulse';
            app.HeatDistributionConstantEditFieldLabel = uilabel(app.InputTab);
            app.HeatDistributionConstantEditFieldLabel.Text = 'Heat Distribution Constant';
            app.HeatDistributionConstantEditField = uieditfield(app.InputTab, 'numeric');
            app.HeatDistributionConstantEditField.Value = 0.00011;
            app.TemperatureofLeftwallEditFieldLabel = uilabel(app.InputTab);
            app.TemperatureofLeftwallEditFieldLabel.Text = {'Temperature of Left wall'; ''};
            app.TemperatureofLeftwallEditField = uieditfield(app.InputTab, 'numeric');
            app.TemperatureofLeftwallEditField.Value = 30;
            app.TemperatureifRightWallEditFieldLabel = uilabel(app.InputTab);
            app.TemperatureifRightWallEditFieldLabel.Text = 'Temperature if Right Wall';
            app.TemperatureifRightWallEditField = uieditfield(app.InputTab, 'numeric');
            app.TemperatureifRightWallEditField.Value = 30;
            app.UITable = uitable(app.InputTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column4'};
            app.UITable.RowName = {};
            app.TemperatureofTopWallEditFieldLabel = uilabel(app.InputTab);
            app.TemperatureofTopWallEditFieldLabel.Text = 'Temperature of Top Wall';
            app.TemperatureofTopWallEditField = uieditfield(app.InputTab, 'numeric');
            app.TemperatureofTopWallEditField.Value = 50;
            app.TemperatureofbottomWallEditFieldLabel = uilabel(app.InputTab);
            app.TemperatureofbottomWallEditFieldLabel.Text = 'Temperature of bottom Wall';
            app.TemperatureofbottomWallEditField = uieditfield(app.InputTab, 'numeric');
            app.TemperatureofbottomWallEditField.Value = 50;
            app.PCBlengthEditFieldLabel = uilabel(app.InputTab);
            app.PCBlengthEditFieldLabel.Text = 'PCB length';
            app.PCBlengthEditField = uieditfield(app.InputTab, 'numeric');
            app.PCBlengthEditField.Value = 1;
            app.PCBwidthEditFieldLabel = uilabel(app.InputTab);
            app.PCBwidthEditFieldLabel.Text = 'PCB width';
            app.PCBwidthEditField = uieditfield(app.InputTab, 'numeric');
            app.PCBwidthEditField.Value = 2;
            app.LengthMustBePositiveLabel = uilabel(app.InputTab);
            app.LengthMustBePositiveLabel.Visible = 'off';
            app.LengthMustBePositiveLabel.Text = 'Length Must Be Positive';
            app.InitialTemperatureEditFieldLabel = uilabel(app.InputTab);
            app.InitialTemperatureEditFieldLabel.Text = 'Initial Temperature ';
            app.InitialTemperatureEditField = uieditfield(app.InputTab, 'numeric');
            app.InitialTemperatureEditField.Value = 40;
            app.TimeStepsEditFieldLabel = uilabel(app.InputTab);
            app.TimeStepsEditFieldLabel.Text = 'Final Time';
            app.TimeStepsEditField = uieditfield(app.InputTab, 'numeric');
            app.TimeStepsEditField.Value = 2000;
            app.x_coordEditFieldLabel = uilabel(app.InputTab);
            app.x_coordEditFieldLabel.Text = 'x_coord';
            app.x_coordEditField = uieditfield(app.InputTab, 'numeric');
            app.x_coordEditField.Value = 0.5;
            app.y_coordEditFieldLabel = uilabel(app.InputTab);
            app.y_coordEditFieldLabel.Text = 'y_coord';
            app.y_coordEditField = uieditfield(app.InputTab, 'numeric');
            app.y_coordEditField.Value = 0.5;
            app.StartSimulationButton = uibutton(app.InputTab, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.InvalidInputLabel = uilabel(app.InputTab);
            app.InvalidInputLabel.Visible = 'off';
            app.InvalidInputLabel.Text = 'Invalid Input';
            app.HotspotDropDownLabel = uilabel(app.InputTab);
            app.HotspotDropDownLabel.Text = 'Hotspot';
            app.HotspotDropDown = uidropdown(app.InputTab);
            app.HotspotDropDown.Items = {'Enable', 'Disable', '', ''};
            app.HotspotDropDown.Value = 'Enable';
            app.x_coordEditFieldLabel_2 = uilabel(app.InputTab);
            app.x_coordEditFieldLabel_2.Text = 'Query Point:';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.UIAxes = uiaxes(app.SimulationTab);
            title(app.UIAxes, 'Heat Distribution SImulation ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ValEditFieldLabel = uilabel(app.SimulationTab);
            app.ValEditFieldLabel.Text = 'Val';
            app.ValEditField = uieditfield(app.SimulationTab, 'numeric');
            app.x_coordEditFieldLabel_3 = uilabel(app.SimulationTab);
            app.x_coordEditFieldLabel_3.Text = 'Query Point Temperature:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end